classdef Data_reconciliation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        LoadingthetableTab             matlab.ui.container.Tab
        StartCellEditFieldLabel        matlab.ui.control.Label
        StartCellEditField             matlab.ui.control.EditField
        EndCellEditFieldLabel          matlab.ui.control.Label
        EndCellEditField               matlab.ui.control.EditField
        PleaseselecttheexcelfilepathLabel  matlab.ui.control.Label
        SelectfileButton               matlab.ui.control.Button
        UITable                        matlab.ui.control.Table
        DR_SQP_INPUTTab                matlab.ui.container.Tab
        calculateButton                matlab.ui.control.StateButton
        CollectionEditFieldLabel       matlab.ui.control.Label
        CollectionEditField            matlab.ui.control.NumericEditField
        SDEditFieldLabel               matlab.ui.control.Label
        SDEditField                    matlab.ui.control.EditField
        LBEditFieldLabel               matlab.ui.control.Label
        LBEditField                    matlab.ui.control.EditField
        UBEditFieldLabel               matlab.ui.control.Label
        UBEditField                    matlab.ui.control.EditField
        X0EditFieldLabel               matlab.ui.control.Label
        X0EditField                    matlab.ui.control.EditField
        X_writeEditFieldLabel          matlab.ui.control.Label
        X_writeEditField               matlab.ui.control.EditField
        Equality_writeEditFieldLabel   matlab.ui.control.Label
        Equality_writeEditField        matlab.ui.control.EditField
        TheoutputTab                   matlab.ui.container.Tab
        FinalResultsButton             matlab.ui.control.Button
        UITable2                       matlab.ui.control.Table
        UITable3                       matlab.ui.control.Table
        Final_valuesLabel              matlab.ui.control.Label
        Equality_function_valuesLabel  matlab.ui.control.Label
        PLOTTab                        matlab.ui.container.Tab
        PLOTButton                     matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes3                        matlab.ui.control.UIAxes
    end


    methods (Access = private)

        % Button pushed function: FinalResultsButton
        function FinalResultsButtonPushed(app, event)
            %t1=readtable(fullfile(getappdata(0,'PathName'),getappdata(0,'xlfilename')),'Range',getappdata(0,'XR'),'ReadVariableNames',false);
            t1=getappdata(0,'FV');
            app.UITable2.Data=t1;
            %T1=table2array(t1);
            %vars1={'fvalues'};
            %t1 = t1(:,vars1);
            %t2=readtable(fullfile(getappdata(0,'PathName'),getappdata(0,'xlfilename')),'Range',getappdata(0,'EQR'),'ReadVariableNames',false);
            t2=getappdata(0,'ceq_X');
            app.UITable3.Data=(t2);
            %T2=table2array(t2);
            %vars2={'eqvalues'};
            %t2 = t2(:,vars2);
            %           x0=  app.UITable.Data(:,3);
            %           SD=  app.UITable.Data(:,4);
            %             [mean_absolute_equality_function_values,max_absolute_equality_function_values]=DRSQPMain((x0,SD)
            %             app.UITable3.Data(:,1)= mean_absolute_equality_function_values   ;
            %             app.UITable3.Data(:,2)= max_absolute_equality_function_values ;
            setappdata(0,'rawtable1',t1)
            setappdata(0,'rawtable2',t2)
        end

        % Button pushed function: SelectfileButton
        function SelectfileButtonPushed(app, event)
            %figure;
            [filename, pathname] = uigetfile('*.xlsx');
            if isequal(filename,0)
                msgbox('Please input an Excel file')
            else
                sheetname = 1; % input
                range1 = app.StartCellEditField.Value; % input
                range2 = app.EndCellEditField.Value; % input
                xlrange = [range1 ':' range2];
                [~,~,x] = xlsread(fullfile(pathname,filename),sheetname,xlrange);
                x(cellfun(@(x) ~isempty(x) && isnumeric(x) && isnan(x),x)) = {''};
                
                %% Replace non-numeric cells with NaN
            %                 R = cellfun(@(x) ~isnumeric(x) && ~islogical(x),x); % Find non-numeric cells
            %                 x(R) = {''}; % Replace non-numeric cells
                
                %% Replace blank cells with NaN
                R = cellfun(@(x) isempty(x) || (ischar(x) && all(x==' ')),x);
                x(R) = {''}; % Replace blank cells
                
            %                 idx = cellfun(@ischar, x);
            %                 x(idx) = cellfun(@(x) string(x), x(idx), 'UniformOutput', false);
                %t = readcell(fullfile(path, file));
                app.UITable.Data = (x);
                setappdata(0,'xlfilename',filename);
                setappdata(0,'SQPSheetName',sheetname);
                setappdata(0,'PathName',pathname);
              
                
                % Set column names as a pull down menu
                %app.xDropDown.Items = t.Properties.VariableNames;
            end
            
        end

        % Callback function
        function UploadthevaluesofXButtonValueChanged(app, event)
          %  value = app.UploadthevaluesofXButton.Value;
          [file, path] = uigetfile('*.xlsx');
          if isequal(file,0)
              msgbox('Please input an Excel file')
          else
              sheet = 1; % input
              range1 = 'A1'; % input
              range2 = 'A200'; % input
              xlrange = [range1 ':' range2];
              [~,~,x] = xlsread(fullfile(path,file),sheet,xlrange);
              x(cellfun(@(x) ~isempty(x) && isnumeric(x) && isnan(x),x)) = {''};
              R = cellfun(@(x) isempty(x) || (ischar(x) && all(x==' ')),x);
              x(R) = {''};
              %app.UITable.Data = (x);
              y=cell2mat(x);
              x=y;
          end
          
          %[c,ceq]=DR_SQP_Constraints_VandZn(x)
          %app.UITable2.Data=ceq ;
          
        end

        % Value changed function: calculateButton
        function calculateButtonValueChanged(app, event)
            value = app.calculateButton.Value;
            collection = app.CollectionEditField.Value;
            SDrange = app.SDEditField.Value;
            lbrange = app.LBEditField.Value;
            ubrange = app.UBEditField.Value;
            x0range = app.X0EditField.Value;
            
            x_writerange = app.X_writeEditField.Value;
            equality_write_range = app.Equality_writeEditField.Value;
            setappdata(0,'XR',x_writerange);
            setappdata(0,'EQR',equality_write_range);
            setappdata(0,'x0value',x0range);
           
            [x0,final_values,x,ceq_x]=DR_SQP_Main(getappdata(0,'xlfilename'),getappdata(0,'SQPSheetName'),x0range,SDrange,collection,lbrange,ubrange,equality_write_range,x_writerange);
            setappdata(0,'X0',x0);
            %x=DR_SQP_Main(getappdata(0,'xlfilename'),getappdata(0,'SQPSheetName'),x0range,SDrange,collection,lbrange,ubrange,equality_write_range,x_writerange);
            setappdata(0,'X',x);
            setappdata(0,'FV',final_values)
            %ceq_x=DR_SQP_Main(getappdata(0,'xlfilename'),getappdata(0,'SQPSheetName'),x0range,SDrange,collection,lbrange,ubrange,equality_write_range,x_writerange);
            setappdata(0,'ceq_X',ceq_x);
        end

        % Button pushed function: PLOTButton
        function PLOTButtonPushed(app, event)
            bar(app.UIAxes,getappdata(0,'rawtable1'))
            bar(app.UIAxes3,getappdata(0,'rawtable2'))
            r1=table2array(readtable(fullfile(getappdata(0,'PathName'),getappdata(0,'xlfilename')),'Range',char(getappdata(0,'x0value')),'ReadVariableNames',false));
            r2=((getappdata(0,'rawtable1')-r1)./r1*100)
            bar(app.UIAxes2,r2)
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LoadingthetableTab = uitab(app.TabGroup);
            app.LoadingthetableTab.Title = 'Loading the table';
            app.StartCellEditFieldLabel = uilabel(app.LoadingthetableTab);
            app.StartCellEditFieldLabel.Text = 'Start Cell';
            app.StartCellEditField = uieditfield(app.LoadingthetableTab, 'text');
            app.EndCellEditFieldLabel = uilabel(app.LoadingthetableTab);
            app.EndCellEditFieldLabel.Text = 'End Cell';
            app.EndCellEditField = uieditfield(app.LoadingthetableTab, 'text');
            app.PleaseselecttheexcelfilepathLabel = uilabel(app.LoadingthetableTab);
            app.PleaseselecttheexcelfilepathLabel.Text = 'Please select the excel file path:';
            app.SelectfileButton = uibutton(app.LoadingthetableTab, 'push');
            app.SelectfileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectfileButtonPushed, true);
            app.SelectfileButton.Text = 'Select file';
            app.UITable = uitable(app.LoadingthetableTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.DR_SQP_INPUTTab = uitab(app.TabGroup);
            app.DR_SQP_INPUTTab.Title = 'DR_SQP_INPUT';
            app.calculateButton = uibutton(app.DR_SQP_INPUTTab, 'state');
            app.calculateButton.ValueChangedFcn = createCallbackFcn(app, @calculateButtonValueChanged, true);
            app.calculateButton.Text = 'calculate ';
            app.CollectionEditFieldLabel = uilabel(app.DR_SQP_INPUTTab);
            app.CollectionEditFieldLabel.Text = 'Collection';
            app.CollectionEditField = uieditfield(app.DR_SQP_INPUTTab, 'numeric');
            app.SDEditFieldLabel = uilabel(app.DR_SQP_INPUTTab);
            app.SDEditFieldLabel.Text = 'SD';
            app.SDEditField = uieditfield(app.DR_SQP_INPUTTab, 'text');
            app.LBEditFieldLabel = uilabel(app.DR_SQP_INPUTTab);
            app.LBEditFieldLabel.Text = 'LB';
            app.LBEditField = uieditfield(app.DR_SQP_INPUTTab, 'text');
            app.UBEditFieldLabel = uilabel(app.DR_SQP_INPUTTab);
            app.UBEditFieldLabel.Text = 'UB';
            app.UBEditField = uieditfield(app.DR_SQP_INPUTTab, 'text');
            app.X0EditFieldLabel = uilabel(app.DR_SQP_INPUTTab);
            app.X0EditFieldLabel.Text = 'X0';
            app.X0EditField = uieditfield(app.DR_SQP_INPUTTab, 'text');
            app.X_writeEditFieldLabel = uilabel(app.DR_SQP_INPUTTab);
            app.X_writeEditFieldLabel.Text = 'X_write';
            app.X_writeEditField = uieditfield(app.DR_SQP_INPUTTab, 'text');
            app.Equality_writeEditFieldLabel = uilabel(app.DR_SQP_INPUTTab);
            app.Equality_writeEditFieldLabel.Text = 'Equality_write';
            app.Equality_writeEditField = uieditfield(app.DR_SQP_INPUTTab, 'text');
            app.TheoutputTab = uitab(app.TabGroup);
            app.TheoutputTab.Title = 'The output';
            app.FinalResultsButton = uibutton(app.TheoutputTab, 'push');
            app.FinalResultsButton.ButtonPushedFcn = createCallbackFcn(app, @FinalResultsButtonPushed, true);
            app.FinalResultsButton.Text = 'Final Results';
            app.UITable2 = uitable(app.TheoutputTab);
            app.UITable2.ColumnName = {'column 1'};
            app.UITable2.RowName = {};
            app.UITable3 = uitable(app.TheoutputTab);
            app.UITable3.ColumnName = {'column 2'};
            app.UITable3.RowName = {};
            app.Final_valuesLabel = uilabel(app.TheoutputTab);
            app.Final_valuesLabel.Text = 'Final_values';
            app.Equality_function_valuesLabel = uilabel(app.TheoutputTab);
            app.Equality_function_valuesLabel.Text = 'Equality_function_values';
            app.PLOTTab = uitab(app.TabGroup);
            app.PLOTTab.Title = 'PLOT';
            app.PLOTButton = uibutton(app.PLOTTab, 'push');
            app.PLOTButton.ButtonPushedFcn = createCallbackFcn(app, @PLOTButtonPushed, true);
            app.PLOTButton.Text = 'PLOT';
            app.UIAxes = uiaxes(app.PLOTTab);
            title(app.UIAxes, 'Variables Values at final Iteration')
            xlabel(app.UIAxes, 'variables')
            ylabel(app.UIAxes, 'Variable values')
            app.UIAxes2 = uiaxes(app.PLOTTab);
            title(app.UIAxes2, 'Deviation of variable valuesin final iteration')
            xlabel(app.UIAxes2, 'variables')
            ylabel(app.UIAxes2, 'Deviation %')
            app.UIAxes3 = uiaxes(app.PLOTTab);
            title(app.UIAxes3, 'Equality constraints value at final iteration')
            xlabel(app.UIAxes3, 'Equality constraints')
            ylabel(app.UIAxes3, 'Y')
        end
    end

    methods (Access = public)

        % Construct app
        function app = Data_reconciliation

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end